classdef GDD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        Label                        matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        NotesLabel                   matlab.ui.control.Label
        WindowName                   matlab.ui.control.Label
        CalculationsLabel            matlab.ui.control.Label
        ResizeButton                 matlab.ui.control.Button
        ZoominButton                 matlab.ui.control.StateButton
        ZoomoutButton                matlab.ui.control.Button
        MaxEditFieldLabel            matlab.ui.control.Label
        MaxEditField                 matlab.ui.control.NumericEditField
        MinEditFieldLabel            matlab.ui.control.Label
        MinEditField                 matlab.ui.control.NumericEditField
        MeanEditFieldLabel           matlab.ui.control.Label
        MeanEditField                matlab.ui.control.NumericEditField
        RMSEditFieldLabel            matlab.ui.control.Label
        RMSEditField                 matlab.ui.control.NumericEditField
        PowerFactorLabel             matlab.ui.control.Label
        PowerFactorEditField         matlab.ui.control.NumericEditField
        RealPowerEditFieldLabel      matlab.ui.control.Label
        RealPowerEditField           matlab.ui.control.NumericEditField
        ReactivePowerEditFieldLabel  matlab.ui.control.Label
        ReactivePowerEditField       matlab.ui.control.NumericEditField
        LeftBoundEditFieldLabel      matlab.ui.control.Label
        LeftBoundEditField           matlab.ui.control.NumericEditField
        RightBoundEditFieldLabel     matlab.ui.control.Label
        RightBoundEditField          matlab.ui.control.NumericEditField
        UpdateBoundsButton           matlab.ui.control.Button
        MaxLabel                     matlab.ui.control.Label
        MinLabel                     matlab.ui.control.Label
        MeanLabel                    matlab.ui.control.Label
        RMSLabel                     matlab.ui.control.Label
        PFLabel                      matlab.ui.control.Label
        RealPLabel                   matlab.ui.control.Label
        ReactPLabel                  matlab.ui.control.Label
        FunctionsDropDown_2Label     matlab.ui.control.Label
        FunctionsDropDown            matlab.ui.control.DropDown
        PeaktoPeakLabel              matlab.ui.control.Label
        P2P                          matlab.ui.control.NumericEditField
        P2PLabel                     matlab.ui.control.Label
        SpectrumAnalysisButton       matlab.ui.control.Button
        CrestFactorLabel             matlab.ui.control.Label
        CrestFactorEditFieldLabel    matlab.ui.control.Label
        CrestFactorEditField         matlab.ui.control.NumericEditField
        SaveImgButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp;                 % Description
        allData;                    % Matrix that holds all data
        columnHeaders               % Units for each column
        headerInfo                  % File info
        channelNum;                 % The channel window identifier
        signalType;
        FunctionsDropDownLabel;
        FunctionsDropDownx;
        dataPlot;
        axis;
        xListener; 
        initialXLim;
        initialYLim;
        startIndex;                 % Start index of windowed data
        stopIndex;                  % Stop index of windowed data
        lBound;                     % Left graph boundary
        rBound;                     % Right graph boundary
        lastTimeVal;
    end
    
    methods (Access = private)
        
        function functionBox(app, signalType)
            % Display dropdown for ac or dc
            if signalType == 0             
                app.FunctionsDropDown.Items = {'<Select>', 'Frequency Waveform', 'Waveform Modulation', 'Frequency Spectrum Analysis'};
                app.FunctionsDropDown.Value = '<Select>';
                app.SpectrumAnalysisButton.Visible = 'off'; 
            else
                app.FunctionsDropDown.Visible = 'off';
                app.RMSEditField.Visible = 'off';
                app.RMSEditFieldLabel.Visible = 'off';         
                app.PowerFactorEditField.Visible = 'off';
                app.PowerFactorLabel.Visible = 'off';
                app.ReactivePowerEditField.Visible = 'off';
                app.ReactivePowerEditFieldLabel.Visible = 'off';
                app.RealPowerEditField.Visible = 'off';
                app.RealPowerEditFieldLabel.Visible = 'off';
                app.RMSLabel.Visible = 'off';
                app.PFLabel.Visible = 'off';
                app.RealPLabel.Visible = 'off';
                app.ReactPLabel.Visible = 'off';
                app.CrestFactorLabel.Visible = 'off';
                app.CrestFactorEditField.Visible = 'off';
                app.CrestFactorEditFieldLabel.Visible = 'off';
            end  
        end
        
        function xLimChange(app)
            % Update bound fields
            updateBounds(app);
            
            % Get indices
            [outOfBounds] = getIndices(app);
            
            % Update calculation fields
            calcFieldsInit(app, outOfBounds);
            
            % Due to zoom bug in App Designer - this has to be added
            disableDefaultInteractivity(app.UIAxes);
            
        end
        
        function plotInit(app)
            % Plot data
            app.dataPlot = plot(app.UIAxes, app.allData(1:1:end, 1), app.allData(1:1:end, app.channelNum + 1));

            % Set axis labels
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = app.columnHeaders(app.channelNum + 1);
            
            % Find last time recorded
            sz = size(app.allData);
            lastVal = ceil(app.allData(sz, 1));
            app.lastTimeVal = app.allData(length(app.allData));
            
            % Set XLim
            app.UIAxes.XLim = [0 lastVal(1)]; 
            
            % Create X axis listener
            warning('off','MATLAB:structOnObject');
            addlistener(struct(app.UIAxes).Axes, 'XLim', 'PostSet', @(src, evnt) xLimChange(app));
        end
        
        function calcFieldsInit(app, outOfBounds)            
            % Initialize Calculation fields
            % DC & AC
            [maxValue, minValue, meanValue, rmsValue, P2PValue, crestFValue] = calcMaxMinMeanRMS(app, outOfBounds);
            app.MaxEditField.Value = maxValue;
            app.MinEditField.Value = minValue;
            app.MeanEditField.Value = meanValue;
            app.RMSEditField.Value = rmsValue;
            app.P2P.Value = P2PValue;
            
            % AC
            if app.signalType == 0
                [PFValue, RealPValue, ReactPValue] = calcPowerFunc(app, outOfBounds);
                app.PowerFactorEditField.Value = PFValue;
                app.RealPowerEditField.Value = RealPValue;
                app.ReactivePowerEditField.Value = ReactPValue;
                app.CrestFactorEditField.Value = crestFValue;
            end
        end
    
        function updateBounds(app)
            app.LeftBoundEditField.Value = app.UIAxes.XLim(1);
            app.RightBoundEditField.Value = app.UIAxes.XLim(2);
        end
        
        % Calculates and returns max, min and mean values
        function [maxValue, minValue, meanValue, rmsValue, P2PValue, crestFValue] = calcMaxMinMeanRMS(app, outOfBounds)            
            
            channelData = app.allData(:, app.channelNum + 1);       % Data for current channel
            crestFValue = 0;
            
            if outOfBounds == 1
                maxValue = 0;
                minValue = 0;
                meanValue = 0;
                rmsValue = 0;
                P2PValue = 0;
                crestFValue = 0;
            else
                maxValue = (max(channelData(app.startIndex:app.stopIndex)));
                minValue = min(channelData(app.startIndex:app.stopIndex));
                meanValue = mean(channelData(app.startIndex:app.stopIndex));
                rmsValue = rms(channelData(app.startIndex:app.stopIndex));
                P2PValue = maxValue - minValue;
                if app.signalType == 0
                    crestFValue = peak2rms(channelData(app.startIndex:app.stopIndex));
                end
                
            end
        end
        
        % Get indices function
        function [outOfBounds] = getIndices(app)
            
            % Get bounds
            app.lBound = app.UIAxes.XLim(1);
            app.rBound = app.UIAxes.XLim(2);
            
            % Calculate the distance between the time value from bound and input data
            distance1 = abs(app.allData(:,1) - app.lBound);
            distance2 = abs(app.allData(:,1) - app.rBound);
            
            outOfBounds = 0;    % Out of bounds trigger
            
            % If one cursor exceed the lower bound, get the index of minimum time value of input data
            if app.lBound < min(app.allData(:,1)) && app.rBound <= max(app.allData(:,1)) ...
                    && app.rBound > min(app.allData(:,1))
                app.startIndex = find(app.allData(:,1) == min(app.allData(:,1))); 
                app.stopIndex = find(distance2 == min(distance2));
                
            % If one cursor exceed the upper bound, get the index of maximum time value of input data
            elseif app.rBound > max(app.allData(:,1)) && app.lBound >= min(app.allData(:,1)) ...
                    && app.lBound < max(app.allData(:,1))
                app.startIndex = find(distance1 == min(distance1));
                app.stopIndex = find(app.allData(:,1)== max(app.allData(:,1)));
                
            % If both cursor exceed the input data range, get the index of minimum and maximum time value 
            elseif app.lBound < min(app.allData(:,1)) && app.rBound > max(app.allData(:,1))
                app.startIndex = find(app.allData(:,1)== min(app.allData(:,1))); 
                app.stopIndex = find(app.allData(:,1)== max(app.allData(:,1)));
                
           %if both cursor are in the input data range, get the index of the time values that are closest to the cursor position
            elseif app.lBound >= min(app.allData(:,1)) && app.rBound <= max(app.allData(:,1))
                app.startIndex = find(distance1 == min(distance1));
                app.stopIndex = find(distance2 == min(distance2));
                
            % If both cursor exceed the upper bound 
            elseif app.lBound > max(app.allData(:,1)) && app.rBound > max(app.allData(:,1))
                outOfBounds = 1;
                
            %if both cursor exceed the lower bound
            elseif app.lBound < min(app.allData(:,1)) && app.lBound < min(app.allData(:,1))
                outOfBounds = 1;
            end   
        end
        
        % Modifies labels to appropriate units
        function unitLabelInit(app)
            app.MaxLabel.Text = app.columnHeaders(app.channelNum + 1);
            app.MinLabel.Text = app.columnHeaders(app.channelNum + 1);
            app.MeanLabel.Text = app.columnHeaders(app.channelNum + 1);        
            app.RMSLabel.Text = app.columnHeaders(app.channelNum + 1);
            app.P2PLabel.Text = app.columnHeaders(app.channelNum + 1);
            app.CrestFactorLabel.Text = app.columnHeaders(app.channelNum + 1);
        end         
        
        % Jeremiah Section
        function [PFValue, RealPValue, ReactPValue] = calcPowerFunc(app, outOfBounds)        
            channelData = app.allData(:, app.channelNum + 1);       % Data for current channel
            
            % If no data within boundaries
            if outOfBounds == 1
                PFValue = 0;
                RealPValue = 0;
                ReactPValue = 0;
            
            % Power functions go in here - If they are too long they can be seperate function calls
            else
                PFValue = 0;        % Add function code
                RealPValue = 0;     % Add code
                ReactPValue = 0;    % Add code
            end
            
        end
    end    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Removed for debugging
            % input args = mainapp, signalType, allData, columnHeaders, headerInfo, windowTitle, num
%             % Store data sent from mainApp
%             app.callingApp = mainapp;
%             app.allData = allData; % Pushing all data to stuct
%             app.columnHeaders = columnHeaders;
%             app.headerInfo = headerInfo;
%             app.channelNum = num;
%             app.signalType = signalType;
%             % Edit name of window    
%             app.WindowName.Text = windowTitle;            
% -------------------DebugMode-------------------------------
            app.signalType = 1;
            if app.signalType == 0
                dataFile = 'C:\Users\rasmu\Downloads\AC_SS_Example\Extract_AC_SS.txt';
            else 
                dataFile = "C:\Users\rasmu\Downloads\DC_SS_Example\Extract_DC_SS.txt";
            end
            % Read in all data
            importedData = importdata(dataFile);
                         
            % Store data app
            app.allData = importedData.data();
            app.columnHeaders = importedData.colheaders;
            app.headerInfo = importedData.textdata;
            app.channelNum = 1;

% --------------------------------------------------------------
            %Initialize Window
            plotInit(app);
            
            % Initialize plot interaction
            disableDefaultInteractivity(app.UIAxes);
            pan(app.UIAxes, 'on');
            
            % Initialize function drop-down menu for AC or DC
            functionBox(app, app.signalType);
            
            % Initialize Calculation fields
            [outOfBound] = getIndices(app);
            calcFieldsInit(app, outOfBound);
            app.initialXLim = [app.lBound app.rBound];
            app.initialYLim = app.UIAxes.YLim;
            
            % Plot zero-Line
            if app.signalType == 0
                hold(app.UIAxes);
                plot(app.UIAxes, [0 app.lastTimeVal], [0 0], 'k', 'LineWidth', 0.25);
            end
            % initialize units
            unitLabelInit(app);
            
            % Initialize Bound fields
            updateBounds(app);
        end

        % Value changed function: ZoominButton
        function ZoominButtonValueChanged(app, event)
            value = app.ZoominButton.Value;
            if value == 1
                enableDefaultInteractivity(app.UIAxes);
                app.ZoomoutButton.Enable = 'off';
                app.ResizeButton.Enable = 'off';
                pause(0.5)
                pan(app.UIAxes, 'on');
                zoom(app.UIAxes, 'on');
            else
                disableDefaultInteractivity(app.UIAxes);
                pan(app.UIAxes, 'on');
                app.ZoomoutButton.Enable = 'on';
                app.ResizeButton.Enable = 'on';
            end
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            app.UIAxes.XLim = app.initialXLim;
            app.UIAxes.YLimMode = 'auto';
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)
%             if app.UIAxes.XLim == app.initialXLim
%                 app.UIAxes.YLim = [app.initialYLim(1)*1.5 app.initialYLim(2)*1.5];
%                 disp(app.UIAxes.YLim);
%             else
                zoom(app.UIAxes, .75);  %zoom out by a factor of .75
            %end
        end

        % Button pushed function: UpdateBoundsButton
        function UpdateBoundsButtonPushed(app, event)
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.XLim = [app.LeftBoundEditField.Value app.RightBoundEditField.Value];
        end

        % Value changed function: FunctionsDropDown
        function FunctionsDropDownValueChanged(app, event)
            value = app.FunctionsDropDown.Value;                % Event trigger value
            
            % % Holds data of channel
            channelData = app.allData(:, app.channelNum + 1); 
            
            % The indecis; start -> stop
            startIndx = app.startIndex;
            stopIndx = app.stopIndex;
            
            % Example of how to plot graph in app designer:
            % plot(app.UIAxes, X, Y)
            % app.UIAxes is the target plot axes.
            
            switch value
                case 'Waveform Modulation'
                    
                    % Kadrian add code here
                    disp(value);
                    
                case 'Waveform Frequency'
                    
                    % Kadrian add code here
                    disp(value);                    
                    
                otherwise
                    % Re-plot
                    plot(app.UIAxes, app.allData(1:300:end, 1), app.allData(1:300:end, app.channelNum + 1));
            end
            
        end

        % Button pushed function: SpectrumAnalysisButton
        function SpectrumAnalysisButtonPushed(app, event)
            % Holds data of channel
            channelData = app.allData(:, app.channelNum + 1); 
            
            % The indecis; start -> stop
            startIndx = app.startIndex;
            stopIndx = app.stopIndex;
            
            data = channelData(startIndx:stopIndx);
            
            samplingFr = 100000;            % Sampling frequency
            fNyquist = samplingFr/2;        % Plot from 0Hz up to Nyquist
            samples = length(data);         % # of samples
            numPnts = 2^17;                 % # of points for FFT computation
            
            if samples > numPnts
                fw = data(1:numPnts).*hanning(numPnts);
            else
                fw = data.*hanning(length(data));
            end
            
            ffw = fft(fw,numPnts);                                          % Compute the DFT
            fAxis = linspace(0,fNyquist-samplingFr/numPnts,numPnts/2);      % Creating the frequency axis
            
            % Create Figure
            figure('Name', 'Frequency Spectrum Analysis with Hanning Window', 'NumberTitle','off',...
                'Position',[540 180 942 576]);
            
            % Plot Linear scale
            subplot(2,1,1)
            semilogy(fAxis,abs(ffw(1:length(fAxis))));
            axis([0,samplingFr/2,1e-4,500]);
            xlabel('f(Hz)'); ylabel('|Xr|'); title('Linear Scale'); grid;
            
            %Plot log scale
            subplot(2,1,2)
            semilogx(fAxis,abs(ffw(1:length(fAxis))));
            axis([0,samplingFr/2,1e-4,500]);
            xlabel('f(Hz)'); ylabel('|Xr|'); title('Log Scale'); grid;
        end

        % Button pushed function: SaveImgButton
        function SaveImgButtonPushed(app, event)
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);
            
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes);
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            
            filter = {'*.png'};
            [file, path] = uiputfile(filter);
            fileName = [path, file];
            saveas(fig, fileName, 'png');
            
            delete(fig);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.NotesLabel = uilabel(app.UIFigure);
            app.NotesLabel.Text = 'Notes';
            app.WindowName = uilabel(app.UIFigure);
            app.CalculationsLabel = uilabel(app.UIFigure);
            app.CalculationsLabel.Text = 'Calculations';
            app.ResizeButton = uibutton(app.UIFigure, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.ZoominButton = uibutton(app.UIFigure, 'state');
            app.ZoominButton.ValueChangedFcn = createCallbackFcn(app, @ZoominButtonValueChanged, true);
            app.ZoominButton.Text = 'Zoom in';
            app.ZoomoutButton = uibutton(app.UIFigure, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max:';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.ValueDisplayFormat = '%.3f';
            app.MaxEditField.Editable = 'off';
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min:';
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField.ValueDisplayFormat = '%.3f';
            app.MinEditField.Editable = 'off';
            app.MeanEditFieldLabel = uilabel(app.UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean:';
            app.MeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanEditField.ValueDisplayFormat = '%.3f';
            app.MeanEditField.Editable = 'off';
            app.RMSEditFieldLabel = uilabel(app.UIFigure);
            app.RMSEditFieldLabel.Text = 'RMS:';
            app.RMSEditField = uieditfield(app.UIFigure, 'numeric');
            app.RMSEditField.ValueDisplayFormat = '%.3f';
            app.RMSEditField.Editable = 'off';
            app.PowerFactorLabel = uilabel(app.UIFigure);
            app.PowerFactorLabel.Text = 'Power Factor:';
            app.PowerFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerFactorEditField.Editable = 'off';
            app.RealPowerEditFieldLabel = uilabel(app.UIFigure);
            app.RealPowerEditFieldLabel.Text = 'Real Power:';
            app.RealPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.RealPowerEditField.Editable = 'off';
            app.ReactivePowerEditFieldLabel = uilabel(app.UIFigure);
            app.ReactivePowerEditFieldLabel.Text = 'Reactive Power:';
            app.ReactivePowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReactivePowerEditField.Editable = 'off';
            app.LeftBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LeftBoundEditFieldLabel.Text = 'Left Bound';
            app.LeftBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftBoundEditField.ValueDisplayFormat = '%.5f';
            app.RightBoundEditFieldLabel = uilabel(app.UIFigure);
            app.RightBoundEditFieldLabel.Text = 'Right Bound';
            app.RightBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightBoundEditField.ValueDisplayFormat = '%.5f';
            app.UpdateBoundsButton = uibutton(app.UIFigure, 'push');
            app.UpdateBoundsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateBoundsButtonPushed, true);
            app.UpdateBoundsButton.Text = 'Update Bounds';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'U';
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = 'U';
            app.MeanLabel = uilabel(app.UIFigure);
            app.MeanLabel.Text = 'U';
            app.RMSLabel = uilabel(app.UIFigure);
            app.RMSLabel.Text = 'U';
            app.PFLabel = uilabel(app.UIFigure);
            app.PFLabel.Text = 'U';
            app.RealPLabel = uilabel(app.UIFigure);
            app.RealPLabel.Text = 'U';
            app.ReactPLabel = uilabel(app.UIFigure);
            app.ReactPLabel.Text = 'U';
            app.FunctionsDropDown_2Label = uilabel(app.UIFigure);
            app.FunctionsDropDown_2Label.Text = 'Functions';
            app.FunctionsDropDown = uidropdown(app.UIFigure);
            app.FunctionsDropDown.Items = {};
            app.FunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionsDropDownValueChanged, true);
            app.FunctionsDropDown.Value = {};
            app.PeaktoPeakLabel = uilabel(app.UIFigure);
            app.PeaktoPeakLabel.Text = 'Peak-to-Peak:';
            app.P2P = uieditfield(app.UIFigure, 'numeric');
            app.P2P.ValueDisplayFormat = '%.3f';
            app.P2P.Editable = 'off';
            app.P2PLabel = uilabel(app.UIFigure);
            app.P2PLabel.Text = 'U';
            app.SpectrumAnalysisButton = uibutton(app.UIFigure, 'push');
            app.SpectrumAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumAnalysisButtonPushed, true);
            app.SpectrumAnalysisButton.Text = 'Spectrum Analysis';
            app.CrestFactorLabel = uilabel(app.UIFigure);
            app.CrestFactorLabel.Text = 'U';
            app.CrestFactorEditFieldLabel = uilabel(app.UIFigure);
            app.CrestFactorEditFieldLabel.Text = 'Crest Factor';
            app.CrestFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CrestFactorEditField.Editable = 'off';
            app.SaveImgButton = uibutton(app.UIFigure, 'push');
            app.SaveImgButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImgButtonPushed, true);
            app.SaveImgButton.Text = 'Save Img';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GDD

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end