classdef powerInt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerInterruptUIFigure          matlab.ui.Figure
        TimesInterruptedEditFieldLabel  matlab.ui.control.Label
        TimesInterruptedEditField       matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        InterruptTable                  matlab.ui.control.Table
        VoltageThresholdEditFieldLabel  matlab.ui.control.Label
        VoltageThresholdEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        mainApp         % Handle to GDD app
        columnHeaders   % Unit for each column
        currentData     % Holds current data GUI is working with
        headerInfo      % MetaData of file
        startIndex      % Start index of data subset
        stopIndex       % Stop index of data subset
        colNames        % Names of each column
        sampleFreq      % Sampling frequency % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, currentData, columnHeaders, headerInfo, startIndex, stopIndex, sampleFreq)
            % Initialize input args
            app.mainApp = mainApp;
            app.currentData = currentData;
            app.columnHeaders = columnHeaders;
            app.headerInfo = headerInfo;
            app.startIndex = startIndex;
            app.stopIndex = stopIndex;
            app.sampleFreq = sampleFreq;    
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            
            % Data vectors
            voltageData = app.currentData;

            % Get subset
            voltageData = voltageData(app.startIndex:app.stopIndex);
            
            % Length of data in subset
            n = length(voltageData);
            
            % sampling Frequency
            fs = app.sampleFreq;
            
            % Finding a cycle time that will be the min length for power interrupt
            upZeroCrossing = find(voltageData(1:end-1) <= 0 & voltageData(2:end) > 0);
            PeakDistance = upZeroCrossing(2)-upZeroCrossing(1);
            
            powerInt = zeros(1,n);
            
            % Making a vector of every point that is below 25% of max instantaneous
            % power. 1 if it is below threshold and 0 if not.
            for k = 1:n
               if (abs(voltageData(k)) <= app.VoltageThresholdEditField.Value)
                   powerInt(k) = 1;
               end
            end
            
            % Looks at powerInt vector and determines the start locations
            % and durations of each time power is less than the 25% threshold value
            int_duration = [];
            count = 0;
            int_start = [];
            for k = 1:length(powerInt)
                if powerInt(k) == 1
                    count = count + 1;
                elseif (k ~= 1 && powerInt(k-1) == 1 && powerInt(k) == 0)
                    int_duration = [int_duration ; count];
                    int_start = [int_start ; (k - count)];
                    count = 0;
                end
                if (k ~= 1 && k == (length(powerInt)) && powerInt(length(powerInt)) == 1)
                    int_duration = [int_duration ; count];
                    int_start = [int_start ; (k - count)];
                end
            end
            digits(4);
            % Comparing all potential power interrupts with the length of 1 cycle
            % if it is >= cycle length then it will be considered a power interrupt
            % and will be put in anew vector 'isPowerInt'.
            isPowerInt = [];
            for k = 1:length(int_duration)
                if (int_duration(k)) >= PeakDistance
                    isPowerInt = [isPowerInt ; (int_start(k)./fs) + (app.startIndex/fs)];
                end
            end

            % Prints the results in the appropriate tables and text fields.
            if (~isempty(isPowerInt))
                app.InterruptTable.Data = (isPowerInt.');
            end
            app.TimesInterruptedEditField.Value = length(isPowerInt);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PowerInterruptUIFigure = uifigure;
            app.PowerInterruptUIFigure.Name = 'Power Interrupt';
            app.TimesInterruptedEditFieldLabel = uilabel(app.PowerInterruptUIFigure);
            app.TimesInterruptedEditFieldLabel.Text = 'Times Interrupted:';
            app.TimesInterruptedEditField = uieditfield(app.PowerInterruptUIFigure, 'numeric');
            app.CalculateButton = uibutton(app.PowerInterruptUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.InterruptTable = uitable(app.PowerInterruptUIFigure);
            app.InterruptTable.ColumnName = {'Start Time (s)'};
            app.InterruptTable.RowName = {};
            app.VoltageThresholdEditFieldLabel = uilabel(app.PowerInterruptUIFigure);
            app.VoltageThresholdEditFieldLabel.Text = 'Voltage Threshold';
            app.VoltageThresholdEditField = uieditfield(app.PowerInterruptUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = powerInt(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PowerInterruptUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerInterruptUIFigure)
        end
    end
end